classdef DPS_HMI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        EmersonDeltaVPanel             matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        OPScreenPanel                  matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        V1_gauge_OP                    matlab.ui.control.LinearGauge
        V1Label_3                      matlab.ui.control.Label
        LIC2003A_values_button         matlab.ui.control.Button
        LIC2003APanel                  matlab.ui.container.Panel
        GridLayout6_3                  matlab.ui.container.GridLayout
        LIC2003A_rate                  matlab.ui.control.NumericEditField
        GaininLabel_3                  matlab.ui.control.Label
        LIC2003A_reset                 matlab.ui.control.NumericEditField
        GaininLabel_2                  matlab.ui.control.Label
        LIC2003A_gain                  matlab.ui.control.NumericEditField
        GaininLabel                    matlab.ui.control.Label
        LIC2003A_level_slider_edit     matlab.ui.control.NumericEditField
        LevelinLabel                   matlab.ui.control.Label
        LIC2003A_valve_slider_edit     matlab.ui.control.NumericEditField
        ValveOpeningLabel              matlab.ui.control.Label
        LIC2003A_exit                  matlab.ui.control.Button
        LIC2003A_valve_slider          matlab.ui.control.Slider
        LIC2003A_level_slider          matlab.ui.control.Slider
        LIC2003A_MODE_toggle           matlab.ui.container.ButtonGroup
        LIC2003A_manual_button         matlab.ui.control.ToggleButton
        LIC2003A_auto_button           matlab.ui.control.ToggleButton
        OP_V1_LL_infopopup             matlab.ui.control.EditField
        OP_V1_LL_infobutton            matlab.ui.control.Button
        OP_V1_LL                       matlab.ui.control.EditField
        OP_V1_LH_infopopup             matlab.ui.control.EditField
        OP_V1_LH_infobutton            matlab.ui.control.Button
        OP_V1_LH                       matlab.ui.control.EditField
        Pump2003AButton                matlab.ui.control.StateButton
        LIC2003A_text                  matlab.ui.control.EditField
        Image5                         matlab.ui.control.Image
        MOCKScreenPanel                matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        V1_gauge                       matlab.ui.control.LinearGauge
        V1Label_2                      matlab.ui.control.Label
        V1X_gauge                      matlab.ui.control.LinearGauge
        V1Label                        matlab.ui.control.Label
        FIC2003X_values_button         matlab.ui.control.Button
        FIC2004X_values_button         matlab.ui.control.Button
        FIC2004XPanel                  matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        FIC2004X_MODE_toggle           matlab.ui.container.ButtonGroup
        FIC2004X_manual_button         matlab.ui.control.ToggleButton
        FIC2004X_auto_button           matlab.ui.control.ToggleButton
        FIC2004X_rate                  matlab.ui.control.NumericEditField
        GaininLabel_6                  matlab.ui.control.Label
        FIC2004X_reset                 matlab.ui.control.NumericEditField
        GaininLabel_5                  matlab.ui.control.Label
        FIC2004X_gain                  matlab.ui.control.NumericEditField
        GaininLabel_4                  matlab.ui.control.Label
        FIC2004X_valve_slider_edit     matlab.ui.control.NumericEditField
        Flowrate10GPMLabel_2           matlab.ui.control.Label
        FIC2004X_flowrate_slider_edit  matlab.ui.control.NumericEditField
        Flowrate10GPMLabel             matlab.ui.control.Label
        FIC2004X_exit                  matlab.ui.control.Button
        FIC2004X_valve_slider          matlab.ui.control.Slider
        FIC2004X_flowrate_slider       matlab.ui.control.Slider
        FIC2003XPanel                  matlab.ui.container.Panel
        GridLayout6_2                  matlab.ui.container.GridLayout
        FIC2003X_rate                  matlab.ui.control.NumericEditField
        GaininLabel_9                  matlab.ui.control.Label
        FIC2003X_reset                 matlab.ui.control.NumericEditField
        GaininLabel_8                  matlab.ui.control.Label
        FIC2003X_gain                  matlab.ui.control.NumericEditField
        GaininLabel_7                  matlab.ui.control.Label
        FIC2003X_valve_slider_edit     matlab.ui.control.NumericEditField
        Flowrate10GPMLabel_3           matlab.ui.control.Label
        FIC2003X_flowrate_slider_edit  matlab.ui.control.NumericEditField
        Flowrate10GPMLabel_4           matlab.ui.control.Label
        FIC2003X_exit                  matlab.ui.control.Button
        FIC2003X_valve_slider          matlab.ui.control.Slider
        FIC2003X_flowrate_slider       matlab.ui.control.Slider
        FIC2003X_MODE_toggle           matlab.ui.container.ButtonGroup
        FIC2003X_manual_button         matlab.ui.control.ToggleButton
        FIC2003X_auto_button           matlab.ui.control.ToggleButton
        MOCK_V1X_LL_infopopup          matlab.ui.control.EditField
        MOCK_V1X_LL_infobutton         matlab.ui.control.Button
        MOCK_V1X_LL                    matlab.ui.control.EditField
        MOCK_V1X_LH_infopopup          matlab.ui.control.EditField
        MOCK_V1X_LH_infobutton         matlab.ui.control.Button
        MOCK_V1X_LH                    matlab.ui.control.EditField
        MOCK_V1_LL_infopopup           matlab.ui.control.EditField
        MOCK_V1_LL_infobutton          matlab.ui.control.Button
        MOCK_V1_LL                     matlab.ui.control.EditField
        MOCK_V1_LH_infopopup           matlab.ui.control.EditField
        MOCK_V1_LH_infobutton          matlab.ui.control.Button
        MOCK_V1_LH                     matlab.ui.control.EditField
        Pump2003XButton                matlab.ui.control.StateButton
        Pump2004XButton                matlab.ui.control.StateButton
        FIC2004X_text                  matlab.ui.control.EditField
        FIC2003X_text                  matlab.ui.control.EditField
        Image4                         matlab.ui.control.Image
        OPOutputDisplayPanel           matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        OP_valve                       matlab.ui.control.UIAxes
        OP_levels                      matlab.ui.control.UIAxes
        MOCKOutputDisplayPanel         matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        MOCK_levels                    matlab.ui.control.UIAxes
        MOCK_flowrates                 matlab.ui.control.UIAxes
        GridLayout3                    matlab.ui.container.GridLayout
        OPOutputDisplayButton          matlab.ui.control.Button
        MOCKOutputDisplayButton        matlab.ui.control.Button
        OPScreenButton                 matlab.ui.control.Button
        MOCKScreenButton               matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        
        BaseFigSize (1,2) double = [1200 800];   % will be overwritten in startupFcn
    
        ModelName = 'DPS_model';
        
        uFIC2003X double = 0;   % FIC2003X valve opening %
        uFIC2004X double = 0;   % FIC2004X valve opening %
        uLIC2003A double = 0;   % LIC2003A valve opening %

        ValueUpdateTimer timer

        % Output Display Timer
        TrendTimer
        TrendWindow = 30
        TrendPeriod = 1.0
        TrendN
        TrendIdx = 0
        TrendT0
        LastXAxisUpdateTime = -inf

        TrendLines_flowrates = gobjects(2,1)
        TrendLabels_flowrates = gobjects(2,1)
        FlowBuf_t
        FlowBuf_2003
        FlowBuf_2004
        ValveBuf_2003X
        ValveBuf_2004X

        TrendLines_levels = gobjects(2,1)
        TrendLabels_levels = gobjects(2,1)
        LevelBuf_V1
        LevelBuf_V1X

        TrendLines_OPlevels = gobjects(1,1)
        TrendLabels_OPlevels = gobjects(1,1)

        TrendLines_OPvalve = gobjects(1,1)
        TrendLabels_OPvalve = gobjects(1,1)
        ValveBuf_2003A

        % Cached Simulink RuntimeObjects
        rt_flowrate_2003X
        rt_flowrate_2004X
        rt_flowrate_2003A
        rt_valve_switch_2003X
        rt_valve_switch_2004X
        rt_valve_switch_2003A
        rt_V1_level
        rt_V1X_level
        rt_u_mem_2003X
        rt_u_mem_2004X
        rt_u_mem_2003A
        
    end
    
    methods (Access = private)

        % Define function for caching Simulink RuntimeObjects
        function cacheRuntimeObjects(app)

            app.rt_flowrate_2003X = get_param([app.ModelName '/DT_2003X'], 'RuntimeObject');
            app.rt_flowrate_2004X = get_param([app.ModelName '/DT_2004X'], 'RuntimeObject');
            app.rt_flowrate_2003A = get_param([app.ModelName '/DT_2003A'], 'RuntimeObject');

            app.rt_valve_switch_2003X = get_param([app.ModelName '/valve_switch_2003X'], 'RuntimeObject');
            app.rt_valve_switch_2004X = get_param([app.ModelName '/valve_switch_2004X'], 'RuntimeObject');
            app.rt_valve_switch_2003A = get_param([app.ModelName '/valve_switch_2003A'], 'RuntimeObject');

            app.rt_V1_level = get_param([app.ModelName '/V-1 subsystem'], 'RuntimeObject');
            app.rt_V1X_level = get_param([app.ModelName '/V-1X subsystem'], 'RuntimeObject');

            app.rt_u_mem_2003X = get_param([app.ModelName '/u_mem_2003X'], 'RuntimeObject');
            app.rt_u_mem_2004X = get_param([app.ModelName '/u_mem_2004X'], 'RuntimeObject');
            app.rt_u_mem_2003A = get_param([app.ModelName '/u_mem_2003A'], 'RuntimeObject');

        end
        
        % Define function for updating Value Buttons
        function updateValueButtons(app)
            
            % Update FIC2003X_values_button
            if app.FIC2003X_MODE_toggle.SelectedObject == app.FIC2003X_manual_button
                vo_FIC2003X = app.FIC2003X_valve_slider_edit.Value;
            elseif app.FIC2003X_MODE_toggle.SelectedObject == app.FIC2003X_auto_button
                vo_FIC2003X = app.rt_u_mem_2003X.OutputPort(1).Data;
                app.FIC2003X_valve_slider_edit.Value = vo_FIC2003X;
                app.FIC2003X_valve_slider.Value = vo_FIC2003X;
            end            
            sp_FIC2003X = app.FIC2003X_flowrate_slider_edit.Value;
            pv_FIC2003X = app.rt_flowrate_2003X.OutputPort(1).Data;
            app.FIC2003X_values_button.Text = sprintf("CO: %.1f\nSP: %.1f\nPV: %.1f", vo_FIC2003X, sp_FIC2003X, pv_FIC2003X);

            % Update FIC2004X_values_button
            if app.FIC2004X_MODE_toggle.SelectedObject == app.FIC2004X_manual_button
                vo_FIC2004X = app.FIC2004X_valve_slider_edit.Value;
            elseif app.FIC2004X_MODE_toggle.SelectedObject == app.FIC2004X_auto_button
                vo_FIC2004X = app.rt_u_mem_2004X.OutputPort(1).Data;
                app.FIC2004X_valve_slider_edit.Value = vo_FIC2004X;
                app.FIC2004X_valve_slider.Value = vo_FIC2004X;
            end
            sp_FIC2004X = app.FIC2004X_flowrate_slider_edit.Value;
            pv_FIC2004X = app.rt_flowrate_2004X.OutputPort(1).Data;
            app.FIC2004X_values_button.Text = sprintf("CO: %.1f\nSP: %.1f\nPV: %.1f", vo_FIC2004X, sp_FIC2004X, pv_FIC2004X);

            % Update LIC2003A_values_button
            if app.LIC2003A_MODE_toggle.SelectedObject == app.LIC2003A_manual_button
                vo_LIC2003A = app.LIC2003A_valve_slider_edit.Value;
            elseif app.LIC2003A_MODE_toggle.SelectedObject == app.LIC2003A_auto_button
                vo_LIC2003A = app.rt_u_mem_2003A.OutputPort(1).Data;
                app.LIC2003A_valve_slider_edit.Value = vo_LIC2003A;
                app.LIC2003A_valve_slider.Value = vo_LIC2003A;
            end
            sp_LIC2003A = app.LIC2003A_level_slider_edit.Value;
            pv_LIC2003A = app.rt_V1_level.OutputPort(1).Data;
            app.LIC2003A_values_button.Text = sprintf("CO: %.1f\nSP: %.1f\nPV: %.1f", vo_LIC2003A, sp_LIC2003A, pv_LIC2003A);
            
            % Update V-1/V-1X Level Gauges
            app.V1_gauge.Value  = app.rt_V1_level.OutputPort(1).Data;
            app.V1_gauge_OP.Value  = app.rt_V1_level.OutputPort(1).Data;
            app.V1X_gauge.Value = app.rt_V1X_level.OutputPort(1).Data;
            
            app.V1_gauge_OP.ScaleColorLimits  = [0, app.rt_V1_level.OutputPort(1).Data];
            app.V1_gauge.ScaleColorLimits  = [0, app.rt_V1_level.OutputPort(1).Data];
            app.V1X_gauge.ScaleColorLimits = [0, app.rt_V1X_level.OutputPort(1).Data];
        end
        
        % Define function for initializing flowrate trends
        function initTrend(app)

            app.TrendN = round(app.TrendWindow/app.TrendPeriod) + 1;
            app.FlowBuf_t      = nan(app.TrendN,1);
            app.FlowBuf_2003   = nan(app.TrendN,1);
            app.FlowBuf_2004   = nan(app.TrendN,1);
            app.LevelBuf_V1    = nan(app.TrendN,1);
            app.LevelBuf_V1X   = nan(app.TrendN,1);
            app.ValveBuf_2003X = nan(app.TrendN,1);
            app.ValveBuf_2004X = nan(app.TrendN,1);
            app.ValveBuf_2003A = nan(app.TrendN,1);
            app.TrendIdx = 0;
            app.TrendT0 = tic;

            % MOCK Flowrates
            cla(app.MOCK_flowrates)
            hold(app.MOCK_flowrates, 'on')
            
            app.TrendLines_flowrates(1) = plot(app.MOCK_flowrates, NaN, NaN, 'LineWidth', 1.5, 'Color', '#00B050');
            app.TrendLines_flowrates(2) = plot(app.MOCK_flowrates, NaN, NaN, 'LineWidth', 1.5, 'Color', '#0070C0');
            app.TrendLabels_flowrates(1) = text(app.MOCK_flowrates, NaN, NaN, 'FIC2003X PV (10*GPM)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#00B050');
            app.TrendLabels_flowrates(2) = text(app.MOCK_flowrates, NaN, NaN, 'FIC2004X PV (10*GPM)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#0070C0');
           
            app.TrendLines_flowrates(3) = plot(app.MOCK_flowrates, NaN, NaN, '--', 'LineWidth', 1.5, 'Color', '#00B050');
            app.TrendLines_flowrates(4) = plot(app.MOCK_flowrates, NaN, NaN, '--', 'LineWidth', 1.5, 'Color', '#0070C0');
            app.TrendLabels_flowrates(3) = text(app.MOCK_flowrates, NaN, NaN, 'FIC2003X CO (%)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#00B050');
            app.TrendLabels_flowrates(4) = text(app.MOCK_flowrates, NaN, NaN, 'FIC2004X CO (%)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#0070C0');
            
            grid(app.MOCK_flowrates, 'on')
            xlabel(app.MOCK_flowrates, 'Time (s)')
            ylabel(app.MOCK_flowrates, 'Flowrate (10*GPM) / Valve Opening (%)')
            xlim(app.MOCK_flowrates, [0 app.TrendWindow])
            ylim(app.MOCK_flowrates, [0 100])

            % MOCK Levels
            cla(app.MOCK_levels)
            hold(app.MOCK_levels, 'on')
            app.TrendLines_levels(1) = plot(app.MOCK_levels, NaN, NaN, 'LineWidth', 1.5, 'Color', '#FF9966');
            app.TrendLines_levels(2) = plot(app.MOCK_levels, NaN, NaN, 'LineWidth', 1.5, 'Color', '#FF5050');
            app.TrendLabels_levels(1) = text(app.MOCK_levels, NaN, NaN, 'V1 level (in)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#FF9966');
            app.TrendLabels_levels(2) = text(app.MOCK_levels, NaN, NaN, 'V1X level (in)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#FF5050');
            grid(app.MOCK_levels, 'on')
            xlabel(app.MOCK_levels, 'Time (s)')
            ylabel(app.MOCK_levels, 'Tank Level (in)')
            xlim(app.MOCK_levels, [0 app.TrendWindow])
            ylim(app.MOCK_levels, [0 65])

            % OP Levels
            cla(app.OP_levels)
            hold(app.OP_levels, 'on')
            app.TrendLines_OPlevels(1) = plot(app.OP_levels, NaN, NaN, 'LineWidth', 1.5, 'Color', '#FF9966');
            app.TrendLabels_OPlevels(1) = text(app.OP_levels, NaN, NaN, 'V1 level (in)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#FF9966');
            grid(app.OP_levels, 'on')
            xlabel(app.OP_levels, 'Time (s)')
            ylabel(app.OP_levels, 'Tank Level (in)')
            xlim(app.OP_levels, [0 app.TrendWindow])
            ylim(app.OP_levels, [0 65])

            % OP LIC2003A Valve Opening
            cla(app.OP_valve)
            hold(app.OP_valve, 'on')
            app.TrendLines_OPvalve(1) = plot(app.OP_valve, NaN, NaN, '--', 'LineWidth', 1.5, 'Color', '#D86ECC');
            app.TrendLabels_OPvalve(1) = text(app.OP_valve, NaN, NaN, 'LIC2003A CO (%)', ...
                'FontSize', 8, 'FontWeight', 'normal', 'VerticalAlignment', 'middle', 'Color', '#D86ECC');
            grid(app.OP_valve, 'on')
            xlabel(app.OP_valve, 'Time (s)')
            ylabel(app.OP_valve, 'Valve Opening (%)')
            xlim(app.OP_valve, [0 app.TrendWindow])
            ylim(app.OP_valve, [0 100])

        end

        % Define function for updating flowrate trends
        function updateTrends(app)

            t_now = toc(app.TrendT0);

            % Flowrate datapoints
            y2003 = app.rt_flowrate_2003X.OutputPort(1).Data;
            y2004 = app.rt_flowrate_2004X.OutputPort(1).Data;
        
            % Valve opening datapoints
            u2003X = app.rt_valve_switch_2003X.OutputPort(1).Data;
            u2004X = app.rt_valve_switch_2004X.OutputPort(1).Data;
            u2003A = app.rt_valve_switch_2003A.OutputPort(1).Data;
        
            % Level datapoints
            yV1 = app.rt_V1_level.OutputPort(1).Data;
            yV1X = app.rt_V1X_level.OutputPort(1).Data;

            % Update level alarms using displayed DeltaV levels
            updateLevelAlarms(app, yV1, yV1X);
                    
            app.TrendIdx = app.TrendIdx + 1;
        
            if app.TrendIdx > app.TrendN
                app.TrendIdx = 1;
            end
        
            % Flowrate buffers
            app.FlowBuf_t(app.TrendIdx)     = t_now;
            app.FlowBuf_2003(app.TrendIdx)  = y2003;
            app.FlowBuf_2004(app.TrendIdx)  = y2004;
            
            % Valve opening buffers
            app.ValveBuf_2003X(app.TrendIdx)    = u2003X;
            app.ValveBuf_2004X(app.TrendIdx)    = u2004X;
            app.ValveBuf_2003A(app.TrendIdx) = u2003A;
            
            % Level buffers
            app.LevelBuf_V1(app.TrendIdx)   = yV1;
            app.LevelBuf_V1X(app.TrendIdx)  = yV1X;
        
            t = app.FlowBuf_t;
        
            if all(isnan(t))
                return
            end
            t_latest = max(t, [], 'omitnan');
            t_min = t_latest - app.TrendWindow;
            valid = ~isnan(t) & (t >= t_min);
            
            tt = t(valid);
            yFlow1  = app.FlowBuf_2003(valid);
            yFlow2  = app.FlowBuf_2004(valid);
            yValve1 = app.ValveBuf_2003X(valid);
            yValve2 = app.ValveBuf_2004X(valid);
            yValve3 = app.ValveBuf_2003A(valid);
            yLevel1 = app.LevelBuf_V1(valid);
            yLevel2 = app.LevelBuf_V1X(valid);
        
            [tt, order] = sort(tt);
            yFlow1  = yFlow1(order);
            yFlow2  = yFlow2(order);
            yValve1 = yValve1(order);
            yValve2 = yValve2(order);
            yValve3 = yValve3(order);
            yLevel1 = yLevel1(order);
            yLevel2 = yLevel2(order);
        
            set(app.TrendLines_flowrates(1), 'XData', tt, 'YData', yFlow1);
            set(app.TrendLines_flowrates(2), 'XData', tt, 'YData', yFlow2);
            set(app.TrendLines_flowrates(3), 'XData', tt, 'YData', yValve1);
            set(app.TrendLines_flowrates(4), 'XData', tt, 'YData', yValve2);
            set(app.TrendLines_levels(1), 'XData', tt, 'YData', yLevel1);
            set(app.TrendLines_levels(2), 'XData', tt, 'YData', yLevel2);
            set(app.TrendLines_OPlevels(1), 'XData', tt, 'YData', yLevel1);
            set(app.TrendLines_OPvalve(1), 'XData', tt, 'YData', yValve3);
        
            % Position trend labels
            if ~isempty(tt)
                x_label = t_latest - 7.5;
                if ~isempty(yFlow1)
                    app.TrendLabels_flowrates(1).Position = [x_label, yFlow1(end) - 1.0, 0];
                end
                if ~isempty(yFlow2)
                    app.TrendLabels_flowrates(2).Position = [x_label, yFlow2(end) - 1.0, 0];
                end
                if ~isempty(yValve1)
                    app.TrendLabels_flowrates(3).Position = [x_label, yValve1(end) + 1.0, 0];
                end
                if ~isempty(yValve2)
                    app.TrendLabels_flowrates(4).Position = [x_label, yValve2(end) + 1.0, 0];
                end
                if ~isempty(yLevel1)
                    app.TrendLabels_levels(1).Position = [x_label, yLevel1(end) - 1.0, 0];
                end
                if ~isempty(yLevel2)
                    app.TrendLabels_levels(2).Position = [x_label, yLevel2(end) - 1.0, 0];
                end
                if ~isempty(yLevel1)
                    app.TrendLabels_OPlevels(1).Position = [x_label, yLevel1(end) - 1.0, 0];
                end
                if ~isempty(yValve3)
                    app.TrendLabels_OPvalve(1).Position = [x_label, yValve3(end) + 1.0, 0];
                end
            end
        
            x_min = t_latest - app.TrendWindow;
            x_max = t_latest;
        
            if (t_latest - app.LastXAxisUpdateTime) >= 1.0
                xlim(app.MOCK_flowrates, [x_min x_max])
                xlim(app.MOCK_levels, [x_min x_max])
                xlim(app.OP_levels, [x_min x_max])
                xlim(app.OP_valve, [x_min x_max])
                app.LastXAxisUpdateTime = t_latest;
            end            
        
        end

        % Define function for setting alarm visual state
        function setAlarmState(~, alarmObj, popupObj, isActive)
        
            if isActive
                alarmObj.BackgroundColor = [1 0 0];     % red
                alarmObj.FontColor = [1 1 1];           % white
                popupObj.Visible = 'on';                % automatically show popup
            else
                alarmObj.BackgroundColor = [1 1 1];     % white
                alarmObj.FontColor = [0 0 0];           % black
                % Do not force popup closed here
            end
        
        end

        % Define function for updating level alarms
        function updateLevelAlarms(app, V1_level_displayed, V1X_level_displayed)
        
            % MOCK screen alarms
            setAlarmState(app, app.MOCK_V1_LH,  app.MOCK_V1_LH_infopopup,  V1_level_displayed  >= 51);
            setAlarmState(app, app.MOCK_V1_LL,  app.MOCK_V1_LL_infopopup,  V1_level_displayed  <= 0);
            setAlarmState(app, app.MOCK_V1X_LH, app.MOCK_V1X_LH_infopopup, V1X_level_displayed >= 51);
            setAlarmState(app, app.MOCK_V1X_LL, app.MOCK_V1X_LL_infopopup, V1X_level_displayed <= 0);
        
            % OP screen alarms
            setAlarmState(app, app.OP_V1_LH, app.OP_V1_LH_infopopup, V1_level_displayed >= 51);
            setAlarmState(app, app.OP_V1_LL, app.OP_V1_LL_infopopup, V1_level_displayed <= 0);
        
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Make panels/popups not visible upon startup
            app.MOCKScreenPanel.Visible               = 'off';
            app.OPScreenPanel.Visible                 = 'off';
            app.MOCKOutputDisplayPanel.Visible        = 'off';
            app.OPOutputDisplayPanel.Visible          = 'off';
            app.FIC2003XPanel.Visible                 = 'off';
            app.FIC2004XPanel.Visible                 = 'off';
            app.LIC2003APanel.Visible                 = 'off';
            
            % Initialize alarms to inactive state
            app.MOCK_V1_LH.BackgroundColor  = [1 1 1];
            app.MOCK_V1_LH.FontColor        = [0 0 0];
            app.MOCK_V1_LL.BackgroundColor  = [1 1 1];
            app.MOCK_V1_LL.FontColor        = [0 0 0];
            app.MOCK_V1X_LH.BackgroundColor = [1 1 1];
            app.MOCK_V1X_LH.FontColor       = [0 0 0];
            app.MOCK_V1X_LL.BackgroundColor = [1 1 1];
            app.MOCK_V1X_LL.FontColor       = [0 0 0];
            
            app.OP_V1_LH.BackgroundColor    = [1 1 1];
            app.OP_V1_LH.FontColor          = [0 0 0];
            app.OP_V1_LL.BackgroundColor    = [1 1 1];
            app.OP_V1_LL.FontColor          = [0 0 0];
            
            % Hide alarm description popups on startup
            app.MOCK_V1_LH_infopopup.Visible  = 'off';
            app.MOCK_V1_LL_infopopup.Visible  = 'off';
            app.MOCK_V1X_LH_infopopup.Visible = 'off';
            app.MOCK_V1X_LL_infopopup.Visible = 'off';
            
            app.OP_V1_LH_infopopup.Visible    = 'off';
            app.OP_V1_LL_infopopup.Visible    = 'off';
            
            % Make all slider values 0 upon startup
            app.LIC2003A_valve_slider.Value         = 0;
            app.LIC2003A_level_slider.Value         = 0;
            app.FIC2003X_valve_slider.Value         = 0;
            app.FIC2003X_flowrate_slider.Value      = 0;
            app.FIC2004X_valve_slider.Value         = 0;
            app.FIC2004X_flowrate_slider.Value      = 0;

            % Make all input constant block values 0 upon startup
            set_param([app.ModelName '/uLIC2003A'], 'Value', '0');
            set_param([app.ModelName '/uFIC2003X'], 'Value', '0');
            set_param([app.ModelName '/uFIC2004X'], 'Value', '0');

            % Make manual mode selected upon startup
            app.FIC2003X_MODE_toggle.SelectedObject = app.FIC2003X_manual_button;
            set_param([app.ModelName '/FIC2003X mode'], 'Value', '1');
            app.FIC2004X_MODE_toggle.SelectedObject = app.FIC2004X_manual_button;
            set_param([app.ModelName '/FIC2004X mode'], 'Value', '1');
            app.LIC2003A_MODE_toggle.SelectedObject = app.LIC2003A_manual_button;
            set_param([app.ModelName '/LIC2003A mode'], 'Value', '1');

            % Make pumps OFF upon startup
            app.Pump2003XButton.Value = 0;
            set_param([app.ModelName '/pump FIC2003X'], 'Value', '0');
            app.Pump2004XButton.Value = 0;
            set_param([app.ModelName '/pump FIC2004X'], 'Value', '0');
            app.Pump2003AButton.Value = 0;
            set_param([app.ModelName '/pump LIC2003A'], 'Value', '0');

            % Set controller tuning
            app.FIC2003X_gain.Value    = 1.286;
            app.FIC2003X_reset.Value    = 5.496;
            app.FIC2003X_rate.Value    = 0;
            set_param([app.ModelName '/PID_2003X'], 'P', num2str(app.FIC2003X_gain.Value));
            set_param([app.ModelName '/PID_2003X'], 'I', num2str(app.FIC2003X_gain.Value/app.FIC2003X_reset.Value));
            set_param([app.ModelName '/PID_2003X'], 'D', num2str(app.FIC2003X_rate.Value));

            app.FIC2004X_gain.Value    = 1.198;
            app.FIC2004X_reset.Value    = 5.787;
            app.FIC2004X_rate.Value    = 0;
            set_param([app.ModelName '/PID_2004X'], 'P', num2str(app.FIC2004X_gain.Value));
            set_param([app.ModelName '/PID_2004X'], 'I', num2str(app.FIC2004X_gain.Value/app.FIC2004X_reset.Value));
            set_param([app.ModelName '/PID_2004X'], 'D', num2str(app.FIC2004X_rate.Value));

            app.LIC2003A_gain.Value    = 8.540;
            app.LIC2003A_reset.Value    = 36.034;
            app.LIC2003A_rate.Value    = 0;
            set_param([app.ModelName '/PID_2003A'], 'P', num2str(-app.LIC2003A_gain.Value));
            set_param([app.ModelName '/PID_2003A'], 'I', num2str(-app.LIC2003A_gain.Value/app.LIC2003A_reset.Value));
            set_param([app.ModelName '/PID_2003A'], 'D', num2str(app.LIC2003A_rate.Value));

            % Set sizing
            app.BaseFigSize = app.UIFigure.Position(3:4);  % [width height], records the figure size at startup (baseline)
            comps = findall(app.UIFigure, '-property', 'FontSize');     % Find all components that have a FontSize property and store their baseline
            for c = comps'
                if isempty(getappdata(c, 'BaseFontSize'))
            setappdata(c, 'BaseFontSize', c.FontSize);
                end
            end
            UIFigureSizeChanged(app, []);

            load_system(app.ModelName);     % load Simulink model
            
            % Configure model for simulation pacing and start
            set_param(app.ModelName, 'EnablePacing', 'on', 'PacingRate', '1');       % Turns on pacing and sets rate to 1:1
            set_param(app.ModelName, 'SimulationCommand', 'start');                  % Start simulation
            disp("Simulation of 'app.ModelName' started with pacing at 1:1 real-time.")

            drawnow
            pause(0.1)

            % Cache Simulink RuntimeObjects after simulation starts
            cacheRuntimeObjects(app);
            
            % Create timer for control panel button display
            app.ValueUpdateTimer = timer('ExecutionMode', 'fixedSpacing', 'Period', 1.0, ...
                'TimerFcn', @(~,~)updateValueButtons(app));
            start(app.ValueUpdateTimer);

            % Initialize flowrate trends
            initTrend(app);

            % Create timer for output displays
            app.TrendTimer = timer('ExecutionMode', 'fixedSpacing', 'Period', app.TrendPeriod, ...
                'TimerFcn', @(~,~)updateTrends(app));
            start(app.TrendTimer);
            
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % 1) Manually keep the top-level grid filling the window
            figPos = app.UIFigure.Position;      % [x y w h]
            
            % 2) Compute a scale factor (based on smaller of width/height)
            baseW = app.BaseFigSize(1);
            baseH = app.BaseFigSize(2);
            curW  = figPos(3);
            curH  = figPos(4);
            scale = min(curW/baseW, curH/baseH);

            % clamp the overall scale so fonts stay readable
            scale = max(0.70, min(2.00, scale));

            % 3) Apply scaled font sizes to every component with FontSize
            comps = findall(app.UIFigure, '-property', 'FontSize');
            for c = comps'
                base = getappdata(c, 'BaseFontSize');
                if isempty(base)
                    base = c.FontSize;
                    setappdata(c, 'BaseFontSize', base);
                end
            % Clamp absolute font size range as desired
            c.FontSize = max(4, min(36, round(base * scale)));
            end
        end

        % Button pushed function: MOCKScreenButton
        function MOCKScreenButtonPushed(app, event)
            app.MOCKScreenPanel.Visible             = 'on';
            app.OPScreenPanel.Visible               = 'off';
            app.MOCKOutputDisplayPanel.Visible      = 'off';
            app.OPOutputDisplayPanel.Visible        = 'off';
        end

        % Button pushed function: OPScreenButton
        function OPScreenButtonPushed(app, event)
            app.MOCKScreenPanel.Visible             = 'off';
            app.OPScreenPanel.Visible               = 'on';
            app.MOCKOutputDisplayPanel.Visible      = 'off';
            app.OPOutputDisplayPanel.Visible        = 'off';
        end

        % Button pushed function: MOCKOutputDisplayButton
        function MOCKOutputDisplayButtonPushed(app, event)
            app.MOCKScreenPanel.Visible             = 'off';
            app.OPScreenPanel.Visible               = 'off';
            app.MOCKOutputDisplayPanel.Visible      = 'on';
            app.OPOutputDisplayPanel.Visible        = 'off';
        end

        % Button pushed function: OPOutputDisplayButton
        function OPOutputDisplayButtonPushed(app, event)
            app.MOCKScreenPanel.Visible             = 'off';
            app.OPScreenPanel.Visible               = 'off';
            app.MOCKOutputDisplayPanel.Visible      = 'off';
            app.OPOutputDisplayPanel.Visible        = 'on';
        end

        % Button pushed function: FIC2003X_values_button
        function FIC2003X_values_buttonButtonPushed(app, event)
            app.FIC2003XPanel.Visible       = 'on';
            app.FIC2004XPanel.Visible       = 'off';
        end

        % Button pushed function: FIC2003X_exit
        function FIC2003X_exitButtonPushed(app, event)
             app.FIC2003XPanel.Visible      = 'off';
        end

        % Button pushed function: FIC2004X_values_button
        function FIC2004X_values_buttonButtonPushed(app, event)
            app.FIC2003XPanel.Visible       = 'off';
            app.FIC2004XPanel.Visible       = 'on';
        end

        % Button pushed function: FIC2004X_exit
        function FIC2004X_exitButtonPushed(app, event)
            app.FIC2004XPanel.Visible       = 'off';
        end

        % Button pushed function: LIC2003A_values_button
        function LIC2003A_values_buttonButtonPushed(app, event)
            app.LIC2003APanel.Visible       = 'on';
        end

        % Button pushed function: LIC2003A_exit
        function LIC2003A_exitButtonPushed(app, event)
            app.LIC2003APanel.Visible       = 'off';
        end

        % Value changed function: LIC2003A_valve_slider_edit
        function LIC2003A_valve_slider_editValueChanged(app, event)
            LIC2003A_valve_slider_edit_value = app.LIC2003A_valve_slider_edit.Value;
            LIC2003A_valve_lims = app.LIC2003A_valve_slider.Limits;   % [low high]
            
            % guard against NaN or non-scalar
            if ~isscalar(LIC2003A_valve_slider_edit_value) || isnan(LIC2003A_valve_slider_edit_value)
                LIC2003A_valve_slider_edit_value = app.LIC2003A_valve_slider.Value;     % revert to current slider value
            else
                % clamp to [low, high] without min/max
                if LIC2003A_valve_slider_edit_value > LIC2003A_valve_lims(2)
                    LIC2003A_valve_slider_edit_value = LIC2003A_valve_lims(2);
                elseif LIC2003A_valve_slider_edit_value < LIC2003A_valve_lims(1)
                    LIC2003A_valve_slider_edit_value = LIC2003A_valve_lims(1);
                end
            end

            app.LIC2003A_valve_slider.Value = LIC2003A_valve_slider_edit_value;
            
            set_param([app.ModelName '/uLIC2003A'], 'Value', num2str(LIC2003A_valve_slider_edit_value));
        end

        % Value changed function: LIC2003A_level_slider_edit
        function LIC2003A_level_slider_editValueChanged(app, event)
            LIC2003A_level_slider_edit_value = app.LIC2003A_level_slider_edit.Value;
            LIC2003A_level_lims = app.LIC2003A_level_slider.Limits;   % [low high]
            
            % guard against NaN or non-scalar
            if ~isscalar(LIC2003A_level_slider_edit_value) || isnan(LIC2003A_level_slider_edit_value)
                LIC2003A_level_slider_edit_value = app.LIC2003A_level_slider.Value;     % revert to current slider value
            else
                % clamp to [low, high] without min/max
                if LIC2003A_level_slider_edit_value > LIC2003A_level_lims(2)
                    LIC2003A_level_slider_edit_value = LIC2003A_level_lims(2);
                elseif LIC2003A_level_slider_edit_value < LIC2003A_level_lims(1)
                    LIC2003A_level_slider_edit_value = LIC2003A_level_lims(1);
                end
            end

            app.LIC2003A_level_slider.Value = LIC2003A_level_slider_edit_value;

            set_param([app.ModelName '/spLIC2003A'], 'Value', num2str(LIC2003A_level_slider_edit_value));
        end

        % Value changed function: LIC2003A_valve_slider
        function LIC2003A_valve_sliderValueChanged(app, event)
            LIC2003A_valve_slider_value = app.LIC2003A_valve_slider.Value;
            app.LIC2003A_valve_slider_edit.Value = LIC2003A_valve_slider_value;

            set_param([app.ModelName '/uLIC2003A'], 'Value', num2str(LIC2003A_valve_slider_value));
        end

        % Value changed function: LIC2003A_level_slider
        function LIC2003A_level_sliderValueChanged(app, event)
            LIC2003A_level_slider_value = app.LIC2003A_level_slider.Value;
            app.LIC2003A_level_slider_edit.Value = LIC2003A_level_slider_value;

            set_param([app.ModelName '/spLIC2003A'], 'Value', num2str(LIC2003A_level_slider_value));
        end

        % Value changed function: FIC2003X_valve_slider_edit
        function FIC2003X_valve_slider_editValueChanged(app, event)
            FIC2003X_valve_slider_edit_value = app.FIC2003X_valve_slider_edit.Value;
            FIC2003X_valve_lims = app.FIC2003X_valve_slider.Limits;   % [low high]
            
            % guard against NaN or non-scalar
            if ~isscalar(FIC2003X_valve_slider_edit_value) || isnan(FIC2003X_valve_slider_edit_value)
                FIC2003X_valve_slider_edit_value = app.FIC2003X_valve_slider.Value;     % revert to current slider value
            else
                % clamp to [low, high] without min/max
                if FIC2003X_valve_slider_edit_value > FIC2003X_valve_lims(2)
                    FIC2003X_valve_slider_edit_value = FIC2003X_valve_lims(2);
                elseif FIC2003X_valve_slider_edit_value < FIC2003X_valve_lims(1)
                    FIC2003X_valve_slider_edit_value = FIC2003X_valve_lims(1);
                end
            end

            app.FIC2003X_valve_slider.Value = FIC2003X_valve_slider_edit_value;

            set_param([app.ModelName '/uFIC2003X'], 'Value', num2str(FIC2003X_valve_slider_edit_value));
        end

        % Value changed function: FIC2003X_flowrate_slider_edit
        function FIC2003X_flowrate_slider_editValueChanged(app, event)
            FIC2003X_flowrate_slider_edit_value = app.FIC2003X_flowrate_slider_edit.Value;
            FIC2003X_flowrate_lims = app.FIC2003X_flowrate_slider.Limits;   % [low high]
            
            % guard against NaN or non-scalar
            if ~isscalar(FIC2003X_flowrate_slider_edit_value) || isnan(FIC2003X_flowrate_slider_edit_value)
                FIC2003X_flowrate_slider_edit_value = app.FIC2003X_flowrate_slider.Value;     % revert to current slider value
            else
                % clamp to [low, high] without min/max
                if FIC2003X_flowrate_slider_edit_value > FIC2003X_flowrate_lims(2)
                    FIC2003X_flowrate_slider_edit_value = FIC2003X_flowrate_lims(2);
                elseif FIC2003X_flowrate_slider_edit_value < FIC2003X_flowrate_lims(1)
                    FIC2003X_flowrate_slider_edit_value = FIC2003X_flowrate_lims(1);
                end
            end

            app.FIC2003X_flowrate_slider.Value = FIC2003X_flowrate_slider_edit_value;

            set_param([app.ModelName '/spFIC2003X'], 'Value', num2str(FIC2003X_flowrate_slider_edit_value));
        end

        % Value changed function: FIC2003X_valve_slider
        function FIC2003X_valve_sliderValueChanged(app, event)
            FIC2003X_valve_slider_value = app.FIC2003X_valve_slider.Value;
            app.FIC2003X_valve_slider_edit.Value = FIC2003X_valve_slider_value;

            set_param([app.ModelName '/uFIC2003X'], 'Value', num2str(FIC2003X_valve_slider_value));
        end

        % Value changed function: FIC2003X_flowrate_slider
        function FIC2003X_flowrate_sliderValueChanged(app, event)
            FIC2003X_flowrate_slider_value = app.FIC2003X_flowrate_slider.Value;
            app.FIC2003X_flowrate_slider_edit.Value = FIC2003X_flowrate_slider_value;

            set_param([app.ModelName '/spFIC2003X'], 'Value', num2str(FIC2003X_flowrate_slider_value));
        end

        % Value changed function: FIC2004X_valve_slider_edit
        function FIC2004X_valve_slider_editValueChanged(app, event)
            FIC2004X_valve_slider_edit_value = app.FIC2004X_valve_slider_edit.Value;
            FIC2004X_valve_lims = app.FIC2004X_valve_slider.Limits;   % [low high]
            
            % guard against NaN or non-scalar
            if ~isscalar(FIC2004X_valve_slider_edit_value) || isnan(FIC2004X_valve_slider_edit_value)
                FIC2004X_valve_slider_edit_value = app.FIC2004X_valve_slider.Value;     % revert to current slider value
            else
                % clamp to [low, high] without min/max
                if FIC2004X_valve_slider_edit_value > FIC2004X_valve_lims(2)
                    FIC2004X_valve_slider_edit_value = FIC2004X_valve_lims(2);
                elseif FIC2004X_valve_slider_edit_value < FIC2004X_valve_lims(1)
                    FIC2004X_valve_slider_edit_value = FIC2004X_valve_lims(1);
                end
            end

            app.FIC2004X_valve_slider.Value = FIC2004X_valve_slider_edit_value;

            set_param([app.ModelName '/uFIC2004X'], 'Value', num2str(FIC2004X_valve_slider_edit_value));
        end

        % Value changed function: FIC2004X_flowrate_slider_edit
        function FIC2004X_flowrate_slider_editValueChanged(app, event)
            FIC2004X_flowrate_slider_edit_value = app.FIC2004X_flowrate_slider_edit.Value;
            FIC2004X_flowrate_lims = app.FIC2004X_flowrate_slider.Limits;   % [low high]
            
            % guard against NaN or non-scalar
            if ~isscalar(FIC2004X_flowrate_slider_edit_value) || isnan(FIC2004X_flowrate_slider_edit_value)
                FIC2004X_flowrate_slider_edit_value = app.FIC2004X_flowrate_slider.Value;     % revert to current slider value
            else
                % clamp to [low, high] without min/max
                if FIC2004X_flowrate_slider_edit_value > FIC2004X_flowrate_lims(2)
                    FIC2004X_flowrate_slider_edit_value = FIC2004X_flowrate_lims(2);
                elseif FIC2004X_flowrate_slider_edit_value < FIC2004X_flowrate_lims(1)
                    FIC2004X_flowrate_slider_edit_value = FIC2004X_flowrate_lims(1);
                end
            end

            app.FIC2004X_flowrate_slider.Value = FIC2004X_flowrate_slider_edit_value;

            set_param([app.ModelName '/spFIC2004X'], 'Value', num2str(FIC2004X_flowrate_slider_edit_value));
        end

        % Value changed function: FIC2004X_valve_slider
        function FIC2004X_valve_sliderValueChanged(app, event)
            FIC2004X_valve_slider_value = app.FIC2004X_valve_slider.Value;
            app.FIC2004X_valve_slider_edit.Value = FIC2004X_valve_slider_value;

            set_param([app.ModelName '/uFIC2004X'], 'Value', num2str(FIC2004X_valve_slider_value));
        end

        % Value changed function: FIC2004X_flowrate_slider
        function FIC2004X_flowrate_sliderValueChanged(app, event)
            FIC2004X_flowrate_slider_value = app.FIC2004X_flowrate_slider.Value;
            app.FIC2004X_flowrate_slider_edit.Value = FIC2004X_flowrate_slider_value;

            set_param([app.ModelName '/spFIC2004X'], 'Value', num2str(FIC2004X_flowrate_slider_value));
        end

        % Value changed function: Pump2003AButton
        function Pump2003AButtonValueChanged(app, event)
            if app.Pump2003AButton.Value
                % Pump ON
                app.Pump2003AButton.BackgroundColor = [0.25 0.25 0.25]; % dark grey
                set_param([app.ModelName '/pump LIC2003A'], 'Value', '1');
            else
                % Pump OFF
                app.Pump2003AButton.BackgroundColor = [1 1 1]; % white
                set_param([app.ModelName '/pump LIC2003A'], 'Value', '0');
            end
        end

        % Value changed function: Pump2003XButton
        function Pump2003XButtonValueChanged(app, event)
            if app.Pump2003XButton.Value
                % Pump ON
                app.Pump2003XButton.BackgroundColor = [0.25 0.25 0.25]; % dark grey
                set_param([app.ModelName '/pump FIC2003X'], 'Value', '1');
            else
                % Pump OFF
                app.Pump2003XButton.BackgroundColor = [1 1 1]; % white
                set_param([app.ModelName '/pump FIC2003X'], 'Value', '0');
            end
        end

        % Value changed function: Pump2004XButton
        function Pump2004XButtonValueChanged(app, event)
            if app.Pump2004XButton.Value
                % Pump ON
                app.Pump2004XButton.BackgroundColor = [0.25 0.25 0.25]; % dark grey
                set_param([app.ModelName '/pump FIC2004X'], 'Value', '1');
            else
                % Pump OFF
                app.Pump2004XButton.BackgroundColor = [1 1 1]; % white
                set_param([app.ModelName '/pump FIC2004X'], 'Value', '0');
            end
        end

        % Size changed function: FIC2004X_MODE_toggle
        function FIC2004X_MODE_toggleSizeChanged(app, event)
            FIC2004X_MODE_toggle_w = app.FIC2004X_MODE_toggle.Position(3);   % group width
            FIC2004X_MODE_toggle_h = app.FIC2004X_MODE_toggle.Position(3);   % group height
            % Margins inside the group (pixels)
            FIC2004X_MODE_toggle_margin = 6;
            FIC2004X_MODE_toggle_innerX = FIC2004X_MODE_toggle_margin;
            FIC2004X_MODE_toggle_innerY = FIC2004X_MODE_toggle_margin;
            FIC2004X_MODE_toggle_innerW = max(1, FIC2004X_MODE_toggle_w - 2*FIC2004X_MODE_toggle_margin);
            FIC2004X_MODE_toggle_innerH = max(1, FIC2004X_MODE_toggle_h - 2*FIC2004X_MODE_toggle_margin);   
            
            % Split height into two halves
            FIC2004X_MODE_toggle_hThird = floor(FIC2004X_MODE_toggle_innerH/3);
        
            % AUTO button on top half
            app.FIC2004X_auto_button.Position = [FIC2004X_MODE_toggle_innerX, FIC2004X_MODE_toggle_innerY + FIC2004X_MODE_toggle_hThird, FIC2004X_MODE_toggle_innerW, FIC2004X_MODE_toggle_hThird];
        
            % MANUAL button on bottom half
            app.FIC2004X_manual_button.Position = [FIC2004X_MODE_toggle_innerX, FIC2004X_MODE_toggle_innerY, FIC2004X_MODE_toggle_innerW, FIC2004X_MODE_toggle_hThird];
        end

        % Size changed function: FIC2003X_MODE_toggle
        function FIC2003X_MODE_toggleSizeChanged(app, event)
            FIC2003X_MODE_toggle_w = app.FIC2003X_MODE_toggle.Position(3);   % group width
            FIC2003X_MODE_toggle_h = app.FIC2003X_MODE_toggle.Position(3);   % group height
            % Margins inside the group (pixels)
            FIC2003X_MODE_toggle_margin = 6;
            FIC2003X_MODE_toggle_innerX = FIC2003X_MODE_toggle_margin;
            FIC2003X_MODE_toggle_innerY = FIC2003X_MODE_toggle_margin;
            FIC2003X_MODE_toggle_innerW = max(1, FIC2003X_MODE_toggle_w - 2*FIC2003X_MODE_toggle_margin);
            FIC2003X_MODE_toggle_innerH = max(1, FIC2003X_MODE_toggle_h - 2*FIC2003X_MODE_toggle_margin);   
            
            % Split height into two halves
            FIC2003X_MODE_toggle_hThird = floor(FIC2003X_MODE_toggle_innerH/3);
        
            % AUTO button on top half
            app.FIC2003X_auto_button.Position = [FIC2003X_MODE_toggle_innerX, FIC2003X_MODE_toggle_innerY + FIC2003X_MODE_toggle_hThird, FIC2003X_MODE_toggle_innerW, FIC2003X_MODE_toggle_hThird];
        
            % MANUAL button on bottom half
            app.FIC2003X_manual_button.Position = [FIC2003X_MODE_toggle_innerX, FIC2003X_MODE_toggle_innerY, FIC2003X_MODE_toggle_innerW, FIC2003X_MODE_toggle_hThird];
        end

        % Size changed function: LIC2003A_MODE_toggle
        function LIC2003A_MODE_toggleSizeChanged(app, event)
            LIC2003A_MODE_toggle_w = app.LIC2003A_MODE_toggle.Position(3);   % group width
            LIC2003A_MODE_toggle_h = app.LIC2003A_MODE_toggle.Position(3);   % group height
            % Margins inside the group (pixels)
            LIC2003A_MODE_toggle_margin = 6;
            LIC2003A_MODE_toggle_innerX = LIC2003A_MODE_toggle_margin;
            LIC2003A_MODE_toggle_innerY = LIC2003A_MODE_toggle_margin;
            LIC2003A_MODE_toggle_innerW = max(1, LIC2003A_MODE_toggle_w - 2*LIC2003A_MODE_toggle_margin);
            LIC2003A_MODE_toggle_innerH = max(1, LIC2003A_MODE_toggle_h - 2*LIC2003A_MODE_toggle_margin);   
            
            % Split height into two halves
            LIC2003A_MODE_toggle_hThird = floor(LIC2003A_MODE_toggle_innerH/3);
        
            % AUTO button on top half
            app.LIC2003A_auto_button.Position = [LIC2003A_MODE_toggle_innerX, LIC2003A_MODE_toggle_innerY + LIC2003A_MODE_toggle_hThird, LIC2003A_MODE_toggle_innerW, LIC2003A_MODE_toggle_hThird];
        
            % MANUAL button on bottom half
            app.LIC2003A_manual_button.Position = [LIC2003A_MODE_toggle_innerX, LIC2003A_MODE_toggle_innerY, LIC2003A_MODE_toggle_innerW, LIC2003A_MODE_toggle_hThird];
        end

        % Button pushed function: MOCK_V1_LH_infobutton
        function MOCK_V1_LH_infobuttonButtonPushed(app, event)
            if strcmp(app.MOCK_V1_LH_infopopup.Visible, 'on')
                app.MOCK_V1_LH_infopopup.Visible = 'off';
            else
                app.MOCK_V1_LH_infopopup.Visible = 'on';
            end
        end

        % Button pushed function: MOCK_V1_LL_infobutton
        function MOCK_V1_LL_infobuttonButtonPushed(app, event)
            if strcmp(app.MOCK_V1_LL_infopopup.Visible, 'on')
                app.MOCK_V1_LL_infopopup.Visible = 'off';
            else
                app.MOCK_V1_LL_infopopup.Visible = 'on';
            end
        end

        % Button pushed function: MOCK_V1X_LH_infobutton
        function MOCK_V1X_LH_infobuttonButtonPushed(app, event)
            if strcmp(app.MOCK_V1X_LH_infopopup.Visible, 'on')
                app.MOCK_V1X_LH_infopopup.Visible = 'off';
            else
                app.MOCK_V1X_LH_infopopup.Visible = 'on';
            end
        end

        % Button pushed function: MOCK_V1X_LL_infobutton
        function MOCK_V1X_LL_infobuttonButtonPushed(app, event)
            if strcmp(app.MOCK_V1X_LL_infopopup.Visible, 'on')
                app.MOCK_V1X_LL_infopopup.Visible = 'off';
            else
                app.MOCK_V1X_LL_infopopup.Visible = 'on';
            end
        end

        % Button pushed function: OP_V1_LH_infobutton
        function OP_V1_LH_infobuttonButtonPushed(app, event)
            if strcmp(app.OP_V1_LH_infopopup.Visible, 'on')
                app.OP_V1_LH_infopopup.Visible = 'off';
            else
                app.OP_V1_LH_infopopup.Visible = 'on';
            end
        end

        % Button pushed function: OP_V1_LL_infobutton
        function OP_V1_LL_infobuttonButtonPushed(app, event)
            if strcmp(app.OP_V1_LL_infopopup.Visible, 'on')
                app.OP_V1_LL_infopopup.Visible = 'off';
            else
                app.OP_V1_LL_infopopup.Visible = 'on';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            set_param(app.ModelName, 'SimulationCommand', 'stop');
            set_param([app.ModelName '/uLIC2003A'], 'Value', '0');
            set_param([app.ModelName '/uFIC2003X'], 'Value', '0');
            set_param([app.ModelName '/uFIC2004X'], 'Value', '0');
            set_param([app.ModelName '/spLIC2003A'], 'Value', '0');
            set_param([app.ModelName '/spFIC2003X'], 'Value', '0');
            set_param([app.ModelName '/spFIC2004X'], 'Value', '0');
            set_param([app.ModelName '/FIC2003X mode'], 'Value', '1');
            set_param([app.ModelName '/FIC2004X mode'], 'Value', '1');
            set_param([app.ModelName '/LIC2003A mode'], 'Value', '1');

            delete(app);            
        end

        % Selection changed function: FIC2003X_MODE_toggle
        function FIC2003X_MODE_toggleSelectionChanged(app, event)
            if event.OldValue == app.FIC2003X_auto_button && event.NewValue == app.FIC2003X_manual_button
                u_new = app.rt_u_mem_2003X.OutputPort(1).Data;
                set_param([app.ModelName '/uFIC2003X'], 'Value', num2str(u_new));
                app.FIC2003X_valve_slider.Value = u_new;
                app.FIC2003X_valve_slider_edit.Value = u_new;
            end

            selectedButton = app.FIC2003X_MODE_toggle.SelectedObject;
            if selectedButton == app.FIC2003X_manual_button
                set_param([app.ModelName '/FIC2003X mode'], 'Value', '1');
            elseif selectedButton == app.FIC2003X_auto_button
                set_param([app.ModelName '/FIC2003X mode'], 'Value', '0');
            end            
        end

        % Selection changed function: FIC2004X_MODE_toggle
        function FIC2004X_MODE_toggleSelectionChanged(app, event)
            if event.OldValue == app.FIC2004X_auto_button && event.NewValue == app.FIC2004X_manual_button
                u_new = app.rt_u_mem_2004X.OutputPort(1).Data;
                set_param([app.ModelName '/uFIC2004X'], 'Value', num2str(u_new));
                app.FIC2004X_valve_slider.Value = u_new;
                app.FIC2004X_valve_slider_edit.Value = u_new;
            end
            
            selectedButton = app.FIC2004X_MODE_toggle.SelectedObject;
            if selectedButton == app.FIC2004X_manual_button
                set_param([app.ModelName '/FIC2004X mode'], 'Value', '1');
            elseif selectedButton == app.FIC2004X_auto_button
                set_param([app.ModelName '/FIC2004X mode'], 'Value', '0');
            else
                return
            end
        end

        % Selection changed function: LIC2003A_MODE_toggle
        function LIC2003A_MODE_toggleSelectionChanged(app, event)
            if event.OldValue == app.LIC2003A_auto_button && event.NewValue == app.LIC2003A_manual_button
                u_new = app.rt_u_mem_2003A.OutputPort(1).Data;
                set_param([app.ModelName '/uLIC2003A'], 'Value', num2str(u_new));
                app.LIC2003A_valve_slider.Value = u_new;
                app.LIC2003A_valve_slider_edit.Value = u_new;
            end
            
            selectedButton = app.LIC2003A_MODE_toggle.SelectedObject;
            if selectedButton == app.LIC2003A_manual_button
                set_param([app.ModelName '/LIC2003A mode'], 'Value', '1');
            elseif selectedButton == app.LIC2003A_auto_button
                set_param([app.ModelName '/LIC2003A mode'], 'Value', '0');
            else
                return
            end
        end

        % Value changed function: FIC2003X_gain
        function FIC2003X_gainValueChanged(app, event)
            set_param([app.ModelName '/PID_2003X'], 'P', num2str(app.FIC2003X_gain.Value));
        end

        % Value changed function: FIC2003X_reset
        function FIC2003X_resetValueChanged(app, event)
            set_param([app.ModelName '/PID_2003X'], 'I', num2str(app.FIC2003X_gain.Value/app.FIC2003X_reset.Value));
        end

        % Value changed function: FIC2003X_rate
        function FIC2003X_rateValueChanged(app, event)
            set_param([app.ModelName '/PID_2003X'], 'D', num2str(app.FIC2003X_rate.Value));
        end

        % Value changed function: FIC2004X_gain
        function FIC2004X_gainValueChanged(app, event)
            set_param([app.ModelName '/PID_2004X'], 'P', num2str(app.FIC2004X_gain.Value));
        end

        % Value changed function: FIC2004X_reset
        function FIC2004X_resetValueChanged(app, event)
            set_param([app.ModelName '/PID_2004X'], 'I', num2str(app.FIC2004X_gain.Value/app.FIC2004X_reset.Value));
        end

        % Value changed function: FIC2004X_rate
        function FIC2004X_rateValueChanged(app, event)
            set_param([app.ModelName '/PID_2004X'], 'D', num2str(app.FIC2004X_rate.Value));
        end

        % Value changed function: LIC2003A_gain
        function LIC2003A_gainValueChanged(app, event)
            set_param([app.ModelName '/PID_2003A'], 'P', num2str(-app.LIC2003A_gain.Value));
        end

        % Value changed function: LIC2003A_reset
        function LIC2003A_resetValueChanged(app, event)
            set_param([app.ModelName '/PID_2003A'], 'I', num2str(-app.LIC2003A_gain.Value/app.LIC2003A_reset.Value));
        end

        % Value changed function: LIC2003A_rate
        function LIC2003A_rateValueChanged(app, event)
            set_param([app.ModelName '/PID_2003A'], 'D', num2str(app.LIC2003A_rate.Value));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EmersonDeltaVPanel = uipanel(app.GridLayout);
            app.EmersonDeltaVPanel.Title = 'Emerson DeltaV';
            app.GridLayout2 = uigridlayout(app.EmersonDeltaVPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.MOCKScreenButton = uibutton(app.GridLayout3, 'push');
            app.MOCKScreenButton.ButtonPushedFcn = createCallbackFcn(app, @MOCKScreenButtonPushed, true);
            app.MOCKScreenButton.Text = 'MOCK Screen';
            app.OPScreenButton = uibutton(app.GridLayout3, 'push');
            app.OPScreenButton.ButtonPushedFcn = createCallbackFcn(app, @OPScreenButtonPushed, true);
            app.OPScreenButton.Text = 'OP Screen';
            app.MOCKOutputDisplayButton = uibutton(app.GridLayout3, 'push');
            app.MOCKOutputDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @MOCKOutputDisplayButtonPushed, true);
            app.MOCKOutputDisplayButton.Text = 'MOCK Output Display';
            app.OPOutputDisplayButton = uibutton(app.GridLayout3, 'push');
            app.OPOutputDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @OPOutputDisplayButtonPushed, true);
            app.OPOutputDisplayButton.Text = 'OP Output Display';
            app.MOCKOutputDisplayPanel = uipanel(app.GridLayout2);
            app.MOCKOutputDisplayPanel.Title = 'MOCK Output Display';
            app.MOCKOutputDisplayPanel.Visible = 'off';
            app.GridLayout8 = uigridlayout(app.MOCKOutputDisplayPanel);
            app.MOCK_flowrates = uiaxes(app.GridLayout8);
            title(app.MOCK_flowrates, 'FIC2003X & FIC2004X Flowrates')
            xlabel(app.MOCK_flowrates, 'Time (s)')
            ylabel(app.MOCK_flowrates, 'Flowrate (10*GPM)')
            zlabel(app.MOCK_flowrates, 'Z')
            app.MOCK_levels = uiaxes(app.GridLayout8);
            title(app.MOCK_levels, 'V1 & V1X Tank Levels')
            xlabel(app.MOCK_levels, 'Time (s)')
            ylabel(app.MOCK_levels, 'Level (in)')
            zlabel(app.MOCK_levels, 'Z')
            app.OPOutputDisplayPanel = uipanel(app.GridLayout2);
            app.OPOutputDisplayPanel.Title = 'OP Output Display';
            app.OPOutputDisplayPanel.Visible = 'off';
            app.GridLayout7 = uigridlayout(app.OPOutputDisplayPanel);
            app.OP_levels = uiaxes(app.GridLayout7);
            title(app.OP_levels, 'V-1 Level')
            xlabel(app.OP_levels, 'X')
            ylabel(app.OP_levels, 'Y')
            zlabel(app.OP_levels, 'Z')
            app.OP_valve = uiaxes(app.GridLayout7);
            title(app.OP_valve, 'LIC2003A Valve Opening')
            xlabel(app.OP_valve, 'X')
            ylabel(app.OP_valve, 'Y')
            zlabel(app.OP_valve, 'Z')
            app.MOCKScreenPanel = uipanel(app.GridLayout2);
            app.MOCKScreenPanel.Title = 'MOCK Screen';
            app.MOCKScreenPanel.Visible = 'off';
            app.GridLayout4 = uigridlayout(app.MOCKScreenPanel);
            app.Image4 = uiimage(app.GridLayout4);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'MOCK_gathering4.png');
            app.FIC2003X_text = uieditfield(app.GridLayout4, 'text');
            app.FIC2003X_text.Value = 'FIC2003X';
            app.FIC2004X_text = uieditfield(app.GridLayout4, 'text');
            app.FIC2004X_text.Value = 'FIC2004X';
            app.Pump2004XButton = uibutton(app.GridLayout4, 'state');
            app.Pump2004XButton.ValueChangedFcn = createCallbackFcn(app, @Pump2004XButtonValueChanged, true);
            app.Pump2004XButton.Text = 'Pump - 2004X';
            app.Pump2003XButton = uibutton(app.GridLayout4, 'state');
            app.Pump2003XButton.ValueChangedFcn = createCallbackFcn(app, @Pump2003XButtonValueChanged, true);
            app.Pump2003XButton.Text = 'Pump - 2003X';
            app.MOCK_V1_LH = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1_LH.Value = 'ALARM I';
            app.MOCK_V1_LH_infobutton = uibutton(app.GridLayout4, 'push');
            app.MOCK_V1_LH_infobutton.ButtonPushedFcn = createCallbackFcn(app, @MOCK_V1_LH_infobuttonButtonPushed, true);
            app.MOCK_V1_LH_infobutton.Text = 'i';
            app.MOCK_V1_LH_infopopup = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1_LH_infopopup.Value = 'V-1 above LEVEL HIGH threshold';
            app.MOCK_V1_LL = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1_LL.Value = 'ALARM II';
            app.MOCK_V1_LL_infobutton = uibutton(app.GridLayout4, 'push');
            app.MOCK_V1_LL_infobutton.ButtonPushedFcn = createCallbackFcn(app, @MOCK_V1_LL_infobuttonButtonPushed, true);
            app.MOCK_V1_LL_infobutton.Text = 'i';
            app.MOCK_V1_LL_infopopup = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1_LL_infopopup.Value = 'V-1 below LEVEL LOW threshold';
            app.MOCK_V1X_LH = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1X_LH.Value = 'ALARM III';
            app.MOCK_V1X_LH_infobutton = uibutton(app.GridLayout4, 'push');
            app.MOCK_V1X_LH_infobutton.ButtonPushedFcn = createCallbackFcn(app, @MOCK_V1X_LH_infobuttonButtonPushed, true);
            app.MOCK_V1X_LH_infobutton.Text = 'i';
            app.MOCK_V1X_LH_infopopup = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1X_LH_infopopup.Value = 'V-1X above LEVEL HIGH threshold';
            app.MOCK_V1X_LL = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1X_LL.Value = 'ALARM IV';
            app.MOCK_V1X_LL_infobutton = uibutton(app.GridLayout4, 'push');
            app.MOCK_V1X_LL_infobutton.ButtonPushedFcn = createCallbackFcn(app, @MOCK_V1X_LL_infobuttonButtonPushed, true);
            app.MOCK_V1X_LL_infobutton.Text = 'i';
            app.MOCK_V1X_LL_infopopup = uieditfield(app.GridLayout4, 'text');
            app.MOCK_V1X_LL_infopopup.Value = 'V-1X below LEVEL LOW threshold';
            app.FIC2003XPanel = uipanel(app.GridLayout4);
            app.FIC2003XPanel.Title = 'FIC2003X';
            app.GridLayout6_2 = uigridlayout(app.FIC2003XPanel);
            app.FIC2003X_MODE_toggle = uibuttongroup(app.GridLayout6_2);
            app.FIC2003X_MODE_toggle.SelectionChangedFcn = createCallbackFcn(app, @FIC2003X_MODE_toggleSelectionChanged, true);
            app.FIC2003X_MODE_toggle.Title = 'MODE';
            app.FIC2003X_MODE_toggle.SizeChangedFcn = createCallbackFcn(app, @FIC2003X_MODE_toggleSizeChanged, true);
            app.FIC2003X_auto_button = uitogglebutton(app.FIC2003X_MODE_toggle);
            app.FIC2003X_auto_button.Text = 'AUTO';
            app.FIC2003X_auto_button.Value = true;
            app.FIC2003X_manual_button = uitogglebutton(app.FIC2003X_MODE_toggle);
            app.FIC2003X_manual_button.Text = 'MANUAL';
            app.FIC2003X_flowrate_slider = uislider(app.GridLayout6_2);
            app.FIC2003X_flowrate_slider.MajorTicks = [0 25 50 75 100];
            app.FIC2003X_flowrate_slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.FIC2003X_flowrate_slider.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_flowrate_sliderValueChanged, true);
            app.FIC2003X_flowrate_slider.Value = 0.075;
            app.FIC2003X_valve_slider = uislider(app.GridLayout6_2);
            app.FIC2003X_valve_slider.MajorTicks = [0 25 50 75 100];
            app.FIC2003X_valve_slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.FIC2003X_valve_slider.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_valve_sliderValueChanged, true);
            app.FIC2003X_exit = uibutton(app.GridLayout6_2, 'push');
            app.FIC2003X_exit.ButtonPushedFcn = createCallbackFcn(app, @FIC2003X_exitButtonPushed, true);
            app.FIC2003X_exit.Text = 'X';
            app.Flowrate10GPMLabel_4 = uilabel(app.GridLayout6_2);
            app.Flowrate10GPMLabel_4.Text = 'Flow SP (10*GPM)';
            app.FIC2003X_flowrate_slider_edit = uieditfield(app.GridLayout6_2, 'numeric');
            app.FIC2003X_flowrate_slider_edit.Limits = [0 100];
            app.FIC2003X_flowrate_slider_edit.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_flowrate_slider_editValueChanged, true);
            app.Flowrate10GPMLabel_3 = uilabel(app.GridLayout6_2);
            app.Flowrate10GPMLabel_3.Text = 'Valve Opening (%)';
            app.FIC2003X_valve_slider_edit = uieditfield(app.GridLayout6_2, 'numeric');
            app.FIC2003X_valve_slider_edit.Limits = [0 100];
            app.FIC2003X_valve_slider_edit.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_valve_slider_editValueChanged, true);
            app.GaininLabel_7 = uilabel(app.GridLayout6_2);
            app.GaininLabel_7.Text = 'Gain';
            app.FIC2003X_gain = uieditfield(app.GridLayout6_2, 'numeric');
            app.FIC2003X_gain.Limits = [-100 100];
            app.FIC2003X_gain.ValueDisplayFormat = '%11.3f';
            app.FIC2003X_gain.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_gainValueChanged, true);
            app.GaininLabel_8 = uilabel(app.GridLayout6_2);
            app.GaininLabel_8.Text = 'Reset';
            app.FIC2003X_reset = uieditfield(app.GridLayout6_2, 'numeric');
            app.FIC2003X_reset.Limits = [0 Inf];
            app.FIC2003X_reset.ValueDisplayFormat = '%11.3f';
            app.FIC2003X_reset.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_resetValueChanged, true);
            app.GaininLabel_9 = uilabel(app.GridLayout6_2);
            app.GaininLabel_9.Text = 'Rate';
            app.FIC2003X_rate = uieditfield(app.GridLayout6_2, 'numeric');
            app.FIC2003X_rate.Limits = [0 Inf];
            app.FIC2003X_rate.ValueDisplayFormat = '%11.3f';
            app.FIC2003X_rate.ValueChangedFcn = createCallbackFcn(app, @FIC2003X_rateValueChanged, true);
            app.FIC2004XPanel = uipanel(app.GridLayout4);
            app.FIC2004XPanel.Title = 'FIC2004X';
            app.GridLayout6 = uigridlayout(app.FIC2004XPanel);
            app.FIC2004X_flowrate_slider = uislider(app.GridLayout6);
            app.FIC2004X_flowrate_slider.MajorTicks = [0 25 50 75 100];
            app.FIC2004X_flowrate_slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.FIC2004X_flowrate_slider.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_flowrate_sliderValueChanged, true);
            app.FIC2004X_valve_slider = uislider(app.GridLayout6);
            app.FIC2004X_valve_slider.MajorTicks = [0 25 50 75 100];
            app.FIC2004X_valve_slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.FIC2004X_valve_slider.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_valve_sliderValueChanged, true);
            app.FIC2004X_exit = uibutton(app.GridLayout6, 'push');
            app.FIC2004X_exit.ButtonPushedFcn = createCallbackFcn(app, @FIC2004X_exitButtonPushed, true);
            app.FIC2004X_exit.Text = 'X';
            app.Flowrate10GPMLabel = uilabel(app.GridLayout6);
            app.Flowrate10GPMLabel.Text = 'Flow SP (10*GPM)';
            app.FIC2004X_flowrate_slider_edit = uieditfield(app.GridLayout6, 'numeric');
            app.FIC2004X_flowrate_slider_edit.Limits = [0 100];
            app.FIC2004X_flowrate_slider_edit.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_flowrate_slider_editValueChanged, true);
            app.Flowrate10GPMLabel_2 = uilabel(app.GridLayout6);
            app.Flowrate10GPMLabel_2.Text = 'Valve Opening (%)';
            app.FIC2004X_valve_slider_edit = uieditfield(app.GridLayout6, 'numeric');
            app.FIC2004X_valve_slider_edit.Limits = [0 100];
            app.FIC2004X_valve_slider_edit.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_valve_slider_editValueChanged, true);
            app.GaininLabel_4 = uilabel(app.GridLayout6);
            app.GaininLabel_4.Text = 'Gain';
            app.FIC2004X_gain = uieditfield(app.GridLayout6, 'numeric');
            app.FIC2004X_gain.Limits = [-100 100];
            app.FIC2004X_gain.ValueDisplayFormat = '%11.3f';
            app.FIC2004X_gain.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_gainValueChanged, true);
            app.GaininLabel_5 = uilabel(app.GridLayout6);
            app.GaininLabel_5.Text = 'Reset';
            app.FIC2004X_reset = uieditfield(app.GridLayout6, 'numeric');
            app.FIC2004X_reset.Limits = [0 Inf];
            app.FIC2004X_reset.ValueDisplayFormat = '%11.3f';
            app.FIC2004X_reset.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_resetValueChanged, true);
            app.GaininLabel_6 = uilabel(app.GridLayout6);
            app.GaininLabel_6.Text = 'Rate';
            app.FIC2004X_rate = uieditfield(app.GridLayout6, 'numeric');
            app.FIC2004X_rate.Limits = [0 Inf];
            app.FIC2004X_rate.ValueDisplayFormat = '%11.3f';
            app.FIC2004X_rate.ValueChangedFcn = createCallbackFcn(app, @FIC2004X_rateValueChanged, true);
            app.FIC2004X_MODE_toggle = uibuttongroup(app.GridLayout6);
            app.FIC2004X_MODE_toggle.SelectionChangedFcn = createCallbackFcn(app, @FIC2004X_MODE_toggleSelectionChanged, true);
            app.FIC2004X_MODE_toggle.Title = 'MODE';
            app.FIC2004X_MODE_toggle.SizeChangedFcn = createCallbackFcn(app, @FIC2004X_MODE_toggleSizeChanged, true);
            app.FIC2004X_auto_button = uitogglebutton(app.FIC2004X_MODE_toggle);
            app.FIC2004X_auto_button.Text = 'AUTO';
            app.FIC2004X_auto_button.Value = true;
            app.FIC2004X_manual_button = uitogglebutton(app.FIC2004X_MODE_toggle);
            app.FIC2004X_manual_button.Text = 'MANUAL';
            app.FIC2004X_values_button = uibutton(app.GridLayout4, 'push');
            app.FIC2004X_values_button.ButtonPushedFcn = createCallbackFcn(app, @FIC2004X_values_buttonButtonPushed, true);
            app.FIC2004X_values_button.Text = {'0.0'; '0.0'; '0.0'};
            app.FIC2003X_values_button = uibutton(app.GridLayout4, 'push');
            app.FIC2003X_values_button.ButtonPushedFcn = createCallbackFcn(app, @FIC2003X_values_buttonButtonPushed, true);
            app.FIC2003X_values_button.Text = {'0.0'; '0.0'; '0.0'};
            app.V1Label = uilabel(app.GridLayout4);
            app.V1Label.Text = 'V-1X';
            app.V1X_gauge = uigauge(app.GridLayout4, 'linear');
            app.V1X_gauge.Limits = [0 65];
            app.V1X_gauge.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65];
            app.V1Label_2 = uilabel(app.GridLayout4);
            app.V1Label_2.Text = 'V-1';
            app.V1_gauge = uigauge(app.GridLayout4, 'linear');
            app.V1_gauge.Limits = [0 65];
            app.V1_gauge.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65];
            app.OPScreenPanel = uipanel(app.GridLayout2);
            app.OPScreenPanel.Title = 'OP Screen';
            app.GridLayout5 = uigridlayout(app.OPScreenPanel);
            app.Image5 = uiimage(app.GridLayout5);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'OP_gathering.png');
            app.LIC2003A_text = uieditfield(app.GridLayout5, 'text');
            app.LIC2003A_text.Value = 'LIC2003A';
            app.Pump2003AButton = uibutton(app.GridLayout5, 'state');
            app.Pump2003AButton.ValueChangedFcn = createCallbackFcn(app, @Pump2003AButtonValueChanged, true);
            app.Pump2003AButton.Text = 'Pump - 2003A';
            app.OP_V1_LH = uieditfield(app.GridLayout5, 'text');
            app.OP_V1_LH.Value = 'ALARM I';
            app.OP_V1_LH_infobutton = uibutton(app.GridLayout5, 'push');
            app.OP_V1_LH_infobutton.ButtonPushedFcn = createCallbackFcn(app, @OP_V1_LH_infobuttonButtonPushed, true);
            app.OP_V1_LH_infobutton.Text = 'i';
            app.OP_V1_LH_infopopup = uieditfield(app.GridLayout5, 'text');
            app.OP_V1_LH_infopopup.Value = 'V-1 above LEVEL HIGH threshold';
            app.OP_V1_LL = uieditfield(app.GridLayout5, 'text');
            app.OP_V1_LL.Value = 'ALARM II';
            app.OP_V1_LL_infobutton = uibutton(app.GridLayout5, 'push');
            app.OP_V1_LL_infobutton.ButtonPushedFcn = createCallbackFcn(app, @OP_V1_LL_infobuttonButtonPushed, true);
            app.OP_V1_LL_infobutton.Text = 'i';
            app.OP_V1_LL_infopopup = uieditfield(app.GridLayout5, 'text');
            app.OP_V1_LL_infopopup.Value = 'V-1 below LEVEL LOW threshold';
            app.LIC2003APanel = uipanel(app.GridLayout5);
            app.LIC2003APanel.Title = 'LIC2003A';
            app.GridLayout6_3 = uigridlayout(app.LIC2003APanel);
            app.LIC2003A_MODE_toggle = uibuttongroup(app.GridLayout6_3);
            app.LIC2003A_MODE_toggle.SelectionChangedFcn = createCallbackFcn(app, @LIC2003A_MODE_toggleSelectionChanged, true);
            app.LIC2003A_MODE_toggle.Title = 'MODE';
            app.LIC2003A_MODE_toggle.SizeChangedFcn = createCallbackFcn(app, @LIC2003A_MODE_toggleSizeChanged, true);
            app.LIC2003A_auto_button = uitogglebutton(app.LIC2003A_MODE_toggle);
            app.LIC2003A_auto_button.Text = 'AUTO';
            app.LIC2003A_auto_button.Value = true;
            app.LIC2003A_manual_button = uitogglebutton(app.LIC2003A_MODE_toggle);
            app.LIC2003A_manual_button.Text = 'MANUAL';
            app.LIC2003A_level_slider = uislider(app.GridLayout6_3);
            app.LIC2003A_level_slider.MajorTicks = [0 25 50 75 100];
            app.LIC2003A_level_slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.LIC2003A_level_slider.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_level_sliderValueChanged, true);
            app.LIC2003A_valve_slider = uislider(app.GridLayout6_3);
            app.LIC2003A_valve_slider.MajorTicks = [0 25 50 75 100];
            app.LIC2003A_valve_slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.LIC2003A_valve_slider.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_valve_sliderValueChanged, true);
            app.LIC2003A_exit = uibutton(app.GridLayout6_3, 'push');
            app.LIC2003A_exit.ButtonPushedFcn = createCallbackFcn(app, @LIC2003A_exitButtonPushed, true);
            app.LIC2003A_exit.Text = 'X';
            app.ValveOpeningLabel = uilabel(app.GridLayout6_3);
            app.ValveOpeningLabel.Text = {'Valve'; 'Opening'; '(%)'};
            app.LIC2003A_valve_slider_edit = uieditfield(app.GridLayout6_3, 'numeric');
            app.LIC2003A_valve_slider_edit.Limits = [0 100];
            app.LIC2003A_valve_slider_edit.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_valve_slider_editValueChanged, true);
            app.LevelinLabel = uilabel(app.GridLayout6_3);
            app.LevelinLabel.Text = {'Level'; '(in)'};
            app.LIC2003A_level_slider_edit = uieditfield(app.GridLayout6_3, 'numeric');
            app.LIC2003A_level_slider_edit.Limits = [0 100];
            app.LIC2003A_level_slider_edit.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_level_slider_editValueChanged, true);
            app.GaininLabel = uilabel(app.GridLayout6_3);
            app.GaininLabel.Text = 'Gain';
            app.LIC2003A_gain = uieditfield(app.GridLayout6_3, 'numeric');
            app.LIC2003A_gain.Limits = [-100 100];
            app.LIC2003A_gain.ValueDisplayFormat = '%11.3f';
            app.LIC2003A_gain.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_gainValueChanged, true);
            app.GaininLabel_2 = uilabel(app.GridLayout6_3);
            app.GaininLabel_2.Text = 'Reset';
            app.LIC2003A_reset = uieditfield(app.GridLayout6_3, 'numeric');
            app.LIC2003A_reset.ValueDisplayFormat = '%11.3f';
            app.LIC2003A_reset.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_resetValueChanged, true);
            app.GaininLabel_3 = uilabel(app.GridLayout6_3);
            app.GaininLabel_3.Text = 'Rate';
            app.LIC2003A_rate = uieditfield(app.GridLayout6_3, 'numeric');
            app.LIC2003A_rate.Limits = [0 Inf];
            app.LIC2003A_rate.ValueDisplayFormat = '%11.3f';
            app.LIC2003A_rate.ValueChangedFcn = createCallbackFcn(app, @LIC2003A_rateValueChanged, true);
            app.LIC2003A_values_button = uibutton(app.GridLayout5, 'push');
            app.LIC2003A_values_button.ButtonPushedFcn = createCallbackFcn(app, @LIC2003A_values_buttonButtonPushed, true);
            app.LIC2003A_values_button.Text = {'0.0'; '0.0'; '0.0'};
            app.V1Label_3 = uilabel(app.GridLayout5);
            app.V1Label_3.Text = 'V-1';
            app.V1_gauge_OP = uigauge(app.GridLayout5, 'linear');
            app.V1_gauge_OP.Limits = [0 65];
            app.V1_gauge_OP.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DPS_HMI

            % Associate the Simulink Model
            app.Simulation = simulation('DPS_model');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end